classdef sound_record_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_3                      matlab.ui.container.Panel
        PlayDropDown                 matlab.ui.control.DropDown
        PlayDropDownLabel            matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        TypeofwindowDropDown         matlab.ui.control.DropDown
        TypeofwindowDropDownLabel    matlab.ui.control.Label
        OverlappercentageEditField   matlab.ui.control.NumericEditField
        OverlappercentageLabel       matlab.ui.control.Label
        LengthinmsEditField          matlab.ui.control.NumericEditField
        LengthinmsEditFieldLabel     matlab.ui.control.Label
        OrderoffilterEditField       matlab.ui.control.NumericEditField
        OrderoffilterEditFieldLabel  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        recordButton                 matlab.ui.control.Button
        durationinsTextArea          matlab.ui.control.TextArea
        durationinsTextAreaLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
       
            audioObject = audiorecorder(44100,16,1);
            
            duration = str2double(app.durationinsTextArea.Value{1});
            
            msgbox('Recording started!');
            
            recordblocking(audioObject, duration);
            
            msgbox('Recording stopped!');
            
            assignin('base', 'audioObject', audioObject)
            %-------------------------------------------         
            audioObject = evalin('base', 'audioObject');
            
            x = getaudiodata(audioObject);
            
            Fs = audioObject.SampleRate;

            filename = 'C:\Users\riyad\Desktop\appdesigner\original.wav';
            
            audiowrite(filename,x,44100);
            
            [x, Fs] = audioread(filename);
            
            x = mean(x, 2); % mono
            x = 0.9*x/max(abs(x)); % normalize
            x = resample(x, 8000, Fs);
            Fs=8000;
            disp(size(x))
            
            assignin('base', 'x', x)
            assignin('base', 'Fs', Fs)
            
            %sound(x, Fs);
            
        end

        % Callback function
        function playButtonPushed(app, event)
            
        end

        % Value changed function: OrderoffilterEditField
        function OrderoffilterEditFieldValueChanged(app, event)
            p = app.OrderoffilterEditField.Value; %p is order of filter
            assignin('base', 'p', p);

        end

        % Callback function
        function LengthofwindowinmsSliderValueChanged(app, event)
        
        end

        % Value changed function: TypeofwindowDropDown
        function TypeofwindowDropDownValueChanged(app, event)
            window = app.TypeofwindowDropDown.Value;
            assignin('base', 'window', window);
                   
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            function pf = findPeakFreqs(x, fs, np)

                if nargin < 2,
                    fs = 1;
                end
                
                if nargin < 3,
                    np = 1;
                end
                
                % get FFT
                nFFT = 4*2^nextpow2(length(x));
                X = abs(fft(x, nFFT));
                f = ((1:nFFT)-1)/nFFT*fs;
                
                % we only need lower half
                X = X(1:nFFT/2);
                X = X/max(X); % normalize
                
                % find peak frequency
                [~, locs] = findpeaks(X, 'npeaks', np, 'sortstr', 'descend');
                
                pf = sort( f(locs) );
            end
            
           %------------------------------------------------------------------------
            function X = stackOLA(x, w, ol)

                if nargin < 3
                    ol = 0.5;
                end
                n = length(x);
                nw = length(w);
                step = floor(nw*ol);
                
                count = floor((n-nw)/step) + 1;
                
                X = zeros(nw, count);
                
                for i = 1:count
                    X(:, i) = w .* x( (1:nw) + (i-1)*step );
                end
            end
            
            %-----------------------------------------------------
            function x = pressStack(X , ol)

                if nargin < 2
                    ol = 0.5;
                end
                
                [nw, count] = size(X);
                step = floor(nw*ol);
                n = (count-1)*step+nw;
                
                x = zeros(n, 1);
                
                for i = 1:count
                   x( (1:nw) + step*(i-1) ) = x( (1:nw) + step*(i-1) ) + X(:, i);
                end
            end
            %-----------------------------------------------------
            
            function [a, g, e] = myLPC(x, p)

                N = length(x);
                
                % form matrices
                b = x(2:N);
                xz = [x; zeros(p,1)];
                
                A = zeros(N-1, p);
                for i=1:p
                    temp = circshift(xz, i-1);
                    A(:, i) = temp(1:(N-1));
                end
                
                % solve for a
                a = A\b ;
                % a = inv(transpose(b)b)(b*A);
                
                % calculate variance of errors
                e = b - A*a;
                g = var(e);
            end
            
            %-----------------------------------------------------
            function [ff, p] = lpcFindPitch(x, w, np, ol, fcut, pcut, hcut)
                
                % handle defaults
                if nargin < 4
                    ol = 0.5;
                end
                
                if nargin < 7
                    hcut = 0.5;
                end
                
                if nargin < 6
                    pcut = -30;
                end
                
                if nargin < 5
                    fcut = 0.125;
                end
                
                if nargin < 3
                   np = 5;
                end
                
                X = stackOLA(x, w , ol); % stack the windowed signals
                [~,n] = size(X);
                
                % get peak frequencies 
                f = zeros(np, n); % frequencies
                p = zeros(1, n); % power
                for i = 1:n
                    xi = X(:,i);
                    
                    f(:,i) = findPeakFreqs(xi, 1, np);
                    p(i) = 10*log10(mean(xi.^2)); % in dB
                end
                
                %% check for harmonics
                f0 = f(1,:);
                ff = f./( ones(np, 1) * f0 );
                df = ff - (1:np)' * ones(1, n);
                df = df(1:floor(np/2),:);
                
                % clean up
                ff = f0;
                ddf = max(abs(df));
                ff(ddf > hcut) = 0;
                ff(ff > fcut) = 0;
                ff(p < pcut) = 0;
            end
            %-----------------------------------------------------
            
            function [A, G, E] = lpcEncode(x, p, w, ol)

                if nargin < 4
                    ol = 0.5;
                end
                
                X = stackOLA(x, w, ol); % stack the windowed signals
                [nw, n] = size(X);
                
                % LPC encode
                A = zeros(p, n);
                G = zeros(1, n);
                E = zeros(nw, n);
                for i = 1:n
                    [a, g, e] = myLPC(X(:,i), p);
                    
                    A(:, i) = a;
                    G(i) = g;
                    E(2:nw, i) = e;
                end
            end
            
            %-----------------------------------------------------
            function xhat = lpcDecode(A, GFE, w, lowcut)

                if nargin < 4
                    lowcut = 0;
                end
                
                [ne, n] = size(GFE);
                nw = length(w);
                
                % synthesize estimates for each chunk
                Xhat = zeros(nw, n);
                
                if ne < 2 % GFE is only the signal power
                    
                    for i = 1:n
                        src = randn(nw, 1); % noise
                        Xhat(:,i) = w .* filter( 1, [-1; A(:,i)], sqrt(GFE(i))*src);
                    end
                    
                    % render down to signal
                    xhat = pressStack(Xhat);
                    
                elseif ne < 3, % GFE is the pitch fequency and signal power
                    F = GFE(2,:); % pitch frequency
                    G = GFE(1,:); % power
                    offset = 0;
                    
                    nw2 = round(nw/2);
                    xhat = zeros(nw2*n, 1);
                    
                    for i = 1:n,
                        
                        % create source
                        if F(i) > 0, % pitched
                            src = zeros(nw2,1);
                            
                            step = round(1/F(i));
                            pts = (offset+1):step:nw2;
                            
                            if ~isempty(pts),
                                offset = step + pts(end) - nw2;
                                src(pts) = sqrt(step); % impulse train, compensate power
                            end
                            
                        else
                            src = randn(nw2, 1); % noise
                            offset = 0;
                        end
                        
                        % filter
                        xhat( nw2*i + (1:nw2) ) = filter( 1, [-1; A(:,i)], sqrt(G(i))*src);
                    end
                    
                else % GFE is the error signal
                    for i = 1:n,
                        Xhat(:,i) = w .* filter( 1, [-1; A(:,i)], GFE(:,i));
                    end
                
                    % render down to signal
                    xhat = pressStack(Xhat);
                end
                    
                % dc blocker hack
                if lowcut > 0,
                    [b,a] = butter(10, lowcut, 'high'); 
                    xhat = filter(b,a,xhat);
                end
            end
            %-------------------------------------------------------------------
            x = evalin('base', 'x');
            
            p = evalin('base', 'p');
            ol = evalin('base', 'ol');
            l = evalin('base', 'l');
            Fs = evalin('base', 'Fs');
            window = evalin('base', 'window');
            

            %------------------------------------------------------------------
            
            if strcmp(window, 'Barlett')
                w = bartlett(floor(l*Fs));
            end
            
            if strcmp(window, 'Blackman')
                w = blackman(floor(l*Fs));
            end
            
            if strcmp(window, 'Hanning')
                w = hann(floor(l*Fs), 'periodic');
            end
            
            if strcmp(window, 'Hamming')
                w = hamming(floor(l*Fs));
            end
            
            %----------------------------------------------------------------
            
            [A, G] = lpcEncode(x, p, w, ol); %encode, 
            [F, ~] = lpcFindPitch(x, w, 5); %finding pitch
            disp(size(G));
            disp(size(F));
            xr_wp = lpcDecode(A, G, w); %sr= x reconstructed without pitch
            xr_p = lpcDecode(A, [G; F], w, 200/Fs); %reconstructing with pitch
            
            
            assignin('base', 'xr_wp', xr_wp)
            assignin('base', 'xr_p', xr_p)
            assignin('base', 'A', A)
            assignin('base', 'F', F)
            
            [~,N] = size(F);
            
            plot(app.UIAxes,F);
            
            %--------------------------------------------------------------


        end

        % Callback function
        function OverlapSliderValueChanged(app, event)

        end

        % Value changed function: LengthinmsEditField
        function LengthinmsEditFieldValueChanged(app, event)
            l = app.LengthinmsEditField.Value;
            l = l/1000;
            assignin('base', 'l', l);
        end

        % Value changed function: OverlappercentageEditField
        function OverlappercentageEditFieldValueChanged(app, event)
            ol = app.OverlappercentageEditField.Value;
            ol = ol/100;
            assignin('base', 'ol', ol);
        end

        % Value changed function: PlayDropDown
        function PlayDropDownValueChanged(app, event)
            dropdown = app.PlayDropDown.Value;
            
            x = evalin('base', 'x');
            xr_p = evalin('base', 'xr_p');
            xr_wp = evalin('base', 'xr_wp');
            Fs = evalin('base', 'Fs');

           
            if strcmp(dropdown, 'Original sound')
                sound(x,Fs)
            end
            
            if strcmp(dropdown, 'Decoded audio with pitch')
                sound(xr_p,Fs)
            end
            
            if strcmp(dropdown, 'Decoded audio without pitch')
                sound(xr_wp,Fs)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pitch')
            xlabel(app.UIAxes, 'Segments')
            ylabel(app.UIAxes, {'Mag of fundamental freq'; ''})
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.durationinsTextAreaLabel = uilabel(app.Panel);
            app.durationinsTextAreaLabel.Text = 'duration in s';
            app.durationinsTextArea = uitextarea(app.Panel);
            app.recordButton = uibutton(app.Panel, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'record';
            app.Panel_2 = uipanel(app.UIFigure);
            app.OrderoffilterEditFieldLabel = uilabel(app.Panel_2);
            app.OrderoffilterEditFieldLabel.Text = 'Order of filter';
            app.OrderoffilterEditField = uieditfield(app.Panel_2, 'numeric');
            app.OrderoffilterEditField.ValueChangedFcn = createCallbackFcn(app, @OrderoffilterEditFieldValueChanged, true);
            app.LengthinmsEditFieldLabel = uilabel(app.Panel_2);
            app.LengthinmsEditFieldLabel.Text = 'Length in ms';
            app.LengthinmsEditField = uieditfield(app.Panel_2, 'numeric');
            app.LengthinmsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinmsEditFieldValueChanged, true);
            app.OverlappercentageLabel = uilabel(app.Panel_2);
            app.OverlappercentageLabel.Text = {'Overlap '; 'percentage'};
            app.OverlappercentageEditField = uieditfield(app.Panel_2, 'numeric');
            app.OverlappercentageEditField.ValueChangedFcn = createCallbackFcn(app, @OverlappercentageEditFieldValueChanged, true);
            app.TypeofwindowDropDownLabel = uilabel(app.Panel_2);
            app.TypeofwindowDropDownLabel.Text = {'Type of '; 'window'};
            app.TypeofwindowDropDown = uidropdown(app.Panel_2);
            app.TypeofwindowDropDown.Items = {'Select', 'Hanning', 'Blackman', 'Hamming', 'Barlett'};
            app.TypeofwindowDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofwindowDropDownValueChanged, true);
            app.TypeofwindowDropDown.Value = 'Select';
            app.Panel_3 = uipanel(app.UIFigure);
            app.RunButton = uibutton(app.Panel_3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlayDropDownLabel = uilabel(app.Panel_3);
            app.PlayDropDownLabel.Text = 'Play';
            app.PlayDropDown = uidropdown(app.Panel_3);
            app.PlayDropDown.Items = {'Select', 'Original sound', 'Decoded audio with pitch', 'Decoded audio without pitch'};
            app.PlayDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayDropDownValueChanged, true);
            app.PlayDropDown.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_record_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end